classdef xRayClassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                 matlab.ui.Figure
        footer                     matlab.ui.container.Panel
        DevelopedbyLabel           matlab.ui.control.Label
        MomchilGalabovLabel        matlab.ui.control.Label
        middlesexButton            matlab.ui.control.Button
        gitHubButton               matlab.ui.control.Button
        TrainaNewModelButton       matlab.ui.control.Button
        ClassifyImagesButton       matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        XrayClassifierLabel        matlab.ui.control.Label
        TestanExistingModelButton  matlab.ui.control.Button
    end

    % adds the images folder to the MATLAB path for easier access
    properties (Access = private)
        oldpath = addpath('./images') 
    end


    methods (Access = private)

        % Button pushed function: middlesexButton
        function middlesexButtonPushed(app, event)
            url = 'https://www.mdx.ac.uk/';
            web(url,'-browser');
        end

        % Button pushed function: gitHubButton
        function gitHubButtonPushed(app, event)
            url = 'https://github.com/linderfight/XrayClassifier';
            web(url,'-browser');
        end

        % Button pushed function: TrainaNewModelButton
        function trainNewModelButtonPushed(app, event)
            newModel;
            app.delete;
        end

        % Button pushed function: ClassifyImagesButton
        function classifyImagesButtonPushed(app, event)
            existingModel;
            app.delete;
        end

        % Button pushed function: TestanExistingModelButton
        function testModelButtonPushed(app, event)
            testModel;
            app.delete;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure;
            app.MainWindow.Name = 'X-ray Classifier';
            app.footer = uipanel(app.MainWindow);
            app.DevelopedbyLabel = uilabel(app.footer);
            app.DevelopedbyLabel.Text = {'Developed by: '; ''};
            app.MomchilGalabovLabel = uilabel(app.footer);
            app.MomchilGalabovLabel.Text = {'Momchil Galabov'; ''};
            app.middlesexButton = uibutton(app.footer, 'push');
            app.middlesexButton.ButtonPushedFcn = createCallbackFcn(app, @middlesexButtonPushed, true);
            app.middlesexButton.Icon = 'mdx-london-logo-red-black.png';
            app.middlesexButton.Text = '';
            app.gitHubButton = uibutton(app.footer, 'push');
            app.gitHubButton.ButtonPushedFcn = createCallbackFcn(app, @gitHubButtonPushed, true);
            app.gitHubButton.Icon = 'github-logo.png';
            app.gitHubButton.Text = '';
            app.TrainaNewModelButton = uibutton(app.MainWindow, 'push');
            app.TrainaNewModelButton.ButtonPushedFcn = createCallbackFcn(app, @trainNewModelButtonPushed, true);
            app.TrainaNewModelButton.Text = 'Train a New Model';
            app.ClassifyImagesButton = uibutton(app.MainWindow, 'push');
            app.ClassifyImagesButton.ButtonPushedFcn = createCallbackFcn(app, @classifyImagesButtonPushed, true);
            app.ClassifyImagesButton.Text = 'Classify Images';
            app.Button = uibutton(app.MainWindow, 'push');
            app.Button.Icon = 'radiology-utsw-banner-600x338.jpg';
            app.Button.Text = '';
            app.XrayClassifierLabel = uilabel(app.MainWindow);
            app.XrayClassifierLabel.Text = 'X-ray Classifier';
            app.TestanExistingModelButton = uibutton(app.MainWindow, 'push');
            app.TestanExistingModelButton.ButtonPushedFcn = createCallbackFcn(app, @testModelButtonPushed, true);
            app.TestanExistingModelButton.Text = 'Test an Existing Model';
        end
    end

    methods (Access = public)

        % Construct app
        function app = xRayClassifier

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end