classdef testModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModelTesting                    matlab.ui.Figure
        footer                          matlab.ui.container.Panel
        gitHubButton                    matlab.ui.control.Button
        middlesexButton                 matlab.ui.control.Button
        DevelopedbyLabel                matlab.ui.control.Label
        MomchilGalabovLabel             matlab.ui.control.Label
        TestModelButton                 matlab.ui.control.Button
        BrowseHealthyPathTest           matlab.ui.control.Button
        BrowseUnhealthyPathTest         matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        JPEGButtonTest                  matlab.ui.control.RadioButton
        JPGButtonTest                   matlab.ui.control.RadioButton
        ImageformatLabel                matlab.ui.control.Label
        HealthyXRayfoldertestdataLabel  matlab.ui.control.Label
        HealthyFolderPathTest           matlab.ui.control.EditField
        UnhealthyXRayfoldertestdataLabel  matlab.ui.control.Label
        UnhealthyFolderPathTest         matlab.ui.control.EditField
        HelpButton                      matlab.ui.control.Button
        BrowseModelButton               matlab.ui.control.Button
        ClassificationmodelLabel        matlab.ui.control.Label
        ModelFolderPath                 matlab.ui.control.EditField
        BrowseTestResultsFolderPathButton  matlab.ui.control.Button
        ResultsFolderLabel              matlab.ui.control.Label
        TestResultsPath                 matlab.ui.control.EditField
    end

    
    methods (Access = private)

        function imageCount = checkImageCount(app, folderPath, imageFormat)
            a = dir(fullfile(folderPath, imageFormat));
            imageCount = size(a,1);
        end
        
        function imageFormatTest = getImageFormatTest(app)
            if app.JPEGButtonTest.Value == 1
                imageFormatTest = '*.jpeg';
            elseif app.JPEGButtonTest.Value == 1
                imageFormatTest = '*.jpg';
            end
        end
        
        function [] = checkPathsTest(app)
            
            healthyFolderPathTest = app.HealthyFolderPathTest.Value;
            unhealthyFolderPathTest = app.UnhealthyFolderPathTest.Value;
            imageFormatTest = getImageFormatTest(app);
            
            healthyTestImageCount = checkImageCount(app, healthyFolderPathTest, imageFormatTest);
            unhealthyTestImageCount = checkImageCount(app, unhealthyFolderPathTest, imageFormatTest);
        
           if healthyTestImageCount < 5
                f = app.ModelTesting;
                message = sprintf('The model requires at least 100 images of type "Healthy" for testing. Make sure the correct image format is specified or add images!');
                uialert(f,message,'Error','Icon','error');
                error('X');
            elseif unhealthyTestImageCount < 5
                f = app.ModelTesting;
                message = sprintf('The model requires at least 100 images of type "Diseased" for testing. Make sure the correct image format is specified or add images!');
                uialert(f,message,'Error','Icon','error');
                error('X');
            end
        end
        
        function actualLabels = generateLabelsTest(app, classes)
            imageFormat = getImageFormatTest(app);
            healthyFolderPathTest = app.HealthyFolderPathTest.Value;
            unhealthyFolderPathTest = app.UnhealthyFolderPathTest.Value;
            testResultsFolderPath = app.TestResultsPath.Value;
            
            cd(unhealthyFolderPathTest);
            A = dir(imageFormat);
            labelsArrA = repmat(classes(1),size(A,1),1);
            
            cd(healthyFolderPathTest);
            N = dir(imageFormat);
            labelsArrN = repmat(classes(2),size(N,1),1);
            
            actualLabels = [labelsArrA; labelsArrN];
            cd(testResultsFolderPath);
            save('Accuracy','actualLabels');
            
        end
        
        function [] = classifyTestData(app, model)
            testDataDirectory = app.TestResultsPath.Value;
            
            cd(testDataDirectory);
            testData = load('TestData.mat');
            
            labels = predict(model,testData.featureVectors);
            predictedLabels = string(labels);
            save('Accuracy','predictedLabels','-append');
            
        end
        
        function [] = extractFeaturesTest(app, numNeighbours, radius)
            featureVectors = zeros(0,0);
            
            healthyFolderPathTest = app.HealthyFolderPathTest.Value;
            unhealthyFolderPathTest = app.UnhealthyFolderPathTest.Value;
            imageFormat = getImageFormatTest(app);
            
            for i = 1 : 2
                
                folderPath = '';
                if i == 1
                    folderPath = unhealthyFolderPathTest;
                elseif i == 2
                    folderPath = healthyFolderPathTest;
                end
                
                imageFiles = dir(fullfile(folderPath,imageFormat));
                
                for j = 1 : length(imageFiles)
                    baseFileName = imageFiles(j).name;
                    fullFileName = fullfile(folderPath, baseFileName);
                    image = imread(fullFileName);
                    
                    if size(image,3)== 3
                        image = rgb2gray(image);
                    end
                    
                    imageLBP = extractLBPFeatures(image,'Upright',false,'Radius',radius,'NumNeighbors',numNeighbours);
                    
                    featureVectors = [featureVectors; imageLBP];
                end
            end
            
            featureVectors = array2table(featureVectors);
            
            save('TestData','featureVectors');    
        end
        
        function accuracy = getAccuracy(app)
            testResultsDirectory = app.TestResultsPath.Value;
            cd(testResultsDirectory);
            
            labels = load('Accuracy.mat');
            accuracy = 0.0;
            
            if length(labels.actualLabels) == length(labels.predictedLabels)
                
                total = length(labels.actualLabels);
                matching = 0;
                
                for k=1:length(labels.actualLabels)
                    if strcmp(labels.actualLabels{k},labels.predictedLabels{k})
                        matching = matching + 1;
                    end
                end
                
                i = (matching / total) * 100;
                accuracy = round(i, 1);
                
            end
        end
    
    end
    

    methods (Access = private)

        % Button pushed function: BrowseHealthyPathTest
        function browseHealthyButtonPushed(app, event)
            folderPath = uigetdir('C://','Select healthy test data');
            
            if folderPath == 0
                return
            end
            app.HealthyFolderPathTest.Value = folderPath;
        end

        % Button pushed function: BrowseUnhealthyPathTest
        function browseDiseasedButtonPushed(app, event)
            folderPath = uigetdir('C://','Select Unhealthy test data');
            
            if folderPath == 0
                return
            end
            app.UnhealthyFolderPathTest.Value = folderPath;
            
        end

        % Button pushed function: TestModelButton
        function testModelButtonPushed(app, event)
            classes = ["abnormal", "normal"];
            modelDirectory = app.ModelFolderPath.Value;
            parentWindow =  app.ModelTesting;
            
             try
                modelPackage = load(modelDirectory);
                model = modelPackage.svmModel;
                numNeighbours = modelPackage.numNeighbours;
                radius = modelPackage.radius;
            catch ME
                message = sprintf('Specified model is not valid. Please choose a valid SVM Model!');
                uialert(parentWindow,message,'Error','Icon','error');
                return;
            end

            progressBar = uiprogressdlg(parentWindow,'Title','Please Wait!');
            
            progressBar.Message = 'Checking Paths...';
            progressBar.Value = 0;
            checkPathsTest(app);
            pause(1);
            
            progressBar.Message = 'Generating Lables...';
            progressBar.Value = 0.2;
            generateLabelsTest(app, classes);
            pause(1);
            
            progressBar.Value = 0.5;
            progressBar.Message = 'Extracting Features...';
            extractFeaturesTest(app, numNeighbours, radius);
            
            
            progressBar.Value = 0.8;
            progressBar.Message = 'Classifying Test Data...';
            classifyTestData(app, model);
            
            progressBar.Value = 0.9;
            progressBar.Message = 'Calculating accuracy...';
            accuracy = getAccuracy(app);
            pause(1.5);
            
            progressBar.Value = 1;
            progressBar.Message = 'Finishing...';
            pause(1);
            
            close(progressBar);

            message = sprintf('You have tested the model! Accuracy: %.3g%%', accuracy);
            uialert(parentWindow,message,'Success','Icon','success');
             
            
        end

        % Button pushed function: BrowseModelButton
        function browseModelButtonPushed(app, event)

            [file,path] = uigetfile('C://','Select a model');
          
            if path == 0
                loadModel(app);
                return
            end
            app.ModelFolderPath.Value = [path,file];
        end

        % Button pushed function: BrowseTestResultsFolderPathButton
        function browseResultsFolderButtonPushed(app, event)
            folderPath = uigetdir('C://','Select healthy test data');
            
            if folderPath == 0
                return
            end
            app.TestResultsPath.Value = folderPath;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModelTesting = uifigure;
            app.ModelTesting.Name = 'Model Testing';
            app.footer = uipanel(app.ModelTesting);
            app.gitHubButton = uibutton(app.footer, 'push');
            app.gitHubButton.Icon = 'github-logo.png';
            app.gitHubButton.Text = '';
            app.middlesexButton = uibutton(app.footer, 'push');
            app.middlesexButton.Icon = 'mdx-london-logo-red-black.png';
            app.middlesexButton.Text = '';
            app.DevelopedbyLabel = uilabel(app.footer);
            app.DevelopedbyLabel.Text = {'Developed by: '; ''};
            app.MomchilGalabovLabel = uilabel(app.footer);
            app.MomchilGalabovLabel.Text = {'Momchil Galabov'; ''};
            app.TestModelButton = uibutton(app.ModelTesting, 'push');
            app.TestModelButton.ButtonPushedFcn = createCallbackFcn(app, @testModelButtonPushed, true);
            app.TestModelButton.Text = 'Test Model';
            app.BrowseHealthyPathTest = uibutton(app.ModelTesting, 'push');
            app.BrowseHealthyPathTest.ButtonPushedFcn = createCallbackFcn(app, @browseHealthyButtonPushed, true);
            app.BrowseHealthyPathTest.Text = 'Browse';
            app.BrowseUnhealthyPathTest = uibutton(app.ModelTesting, 'push');
            app.BrowseUnhealthyPathTest.ButtonPushedFcn = createCallbackFcn(app, @browseDiseasedButtonPushed, true);
            app.BrowseUnhealthyPathTest.Text = 'Browse';
            app.ButtonGroup = uibuttongroup(app.ModelTesting);
            app.JPEGButtonTest = uiradiobutton(app.ButtonGroup);
            app.JPEGButtonTest.Text = 'JPEG';
            app.JPEGButtonTest.Value = true;
            app.JPGButtonTest = uiradiobutton(app.ButtonGroup);
            app.JPGButtonTest.Text = 'JPG';
            app.ImageformatLabel = uilabel(app.ModelTesting);
            app.ImageformatLabel.Text = 'Image format:';
            app.HealthyXRayfoldertestdataLabel = uilabel(app.ModelTesting);
            app.HealthyXRayfoldertestdataLabel.Text = 'Healthy X-Ray folder (test data):';
            app.HealthyFolderPathTest = uieditfield(app.ModelTesting, 'text');
            app.HealthyFolderPathTest.Editable = 'off';
            app.HealthyFolderPathTest.Value = 'C:\Users\momch\Desktop\Final Year project\Implementation\Classifier\Dataset\test\HEALTHY';
            app.UnhealthyXRayfoldertestdataLabel = uilabel(app.ModelTesting);
            app.UnhealthyXRayfoldertestdataLabel.Text = 'Unhealthy X-Ray folder (test data):';
            app.UnhealthyFolderPathTest = uieditfield(app.ModelTesting, 'text');
            app.UnhealthyFolderPathTest.Editable = 'off';
            app.UnhealthyFolderPathTest.Value = 'C:\Users\momch\Desktop\Final Year project\Implementation\Classifier\Dataset\test\DISEASED';
            app.HelpButton = uibutton(app.ModelTesting, 'push');
            app.HelpButton.Icon = 'question.png';
            app.HelpButton.Text = '';
            app.BrowseModelButton = uibutton(app.ModelTesting, 'push');
            app.BrowseModelButton.ButtonPushedFcn = createCallbackFcn(app, @browseModelButtonPushed, true);
            app.BrowseModelButton.Text = 'Browse';
            app.ClassificationmodelLabel = uilabel(app.ModelTesting);
            app.ClassificationmodelLabel.Text = 'Classification model:';
            app.ModelFolderPath = uieditfield(app.ModelTesting, 'text');
            app.ModelFolderPath.Editable = 'off';
            app.ModelFolderPath.Value = 'C:\Users\momch\Desktop\Final Year project\Implementation\Classifier\XrayClassifierApp\preTrainedModel\trainedModel.mat';
            app.BrowseTestResultsFolderPathButton = uibutton(app.ModelTesting, 'push');
            app.BrowseTestResultsFolderPathButton.ButtonPushedFcn = createCallbackFcn(app, @browseResultsFolderButtonPushed, true);
            app.BrowseTestResultsFolderPathButton.Text = 'Browse';
            app.ResultsFolderLabel = uilabel(app.ModelTesting);
            app.ResultsFolderLabel.Text = 'Results Folder:';
            app.TestResultsPath = uieditfield(app.ModelTesting, 'text');
            app.TestResultsPath.Editable = 'off';
            app.TestResultsPath.Value = 'C:\Users\momch\Desktop\Final Year project\Implementation\Classifier\Dataset\test\results';
        end
    end

    methods (Access = public)

        % Construct app
        function app = testModel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModelTesting)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModelTesting)
        end
    end
end